classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        showButton                  matlab.ui.control.Button
        q1rangeEditFieldLabel       matlab.ui.control.Label
        q1rangeEditField            matlab.ui.control.EditField
        q2rangeEditFieldLabel       matlab.ui.control.Label
        q2rangeEditField            matlab.ui.control.EditField
        l1EditFieldLabel            matlab.ui.control.Label
        l1EditField                 matlab.ui.control.EditField
        l2EditFieldLabel            matlab.ui.control.Label
        l2EditField                 matlab.ui.control.EditField
        l3EditFieldLabel            matlab.ui.control.Label
        l3EditField                 matlab.ui.control.EditField
        REditFieldLabel             matlab.ui.control.Label
        REditField                  matlab.ui.control.EditField
        pointsamountEditFieldLabel  matlab.ui.control.Label
        pointsamountEditField       matlab.ui.control.EditField
        Label                       matlab.ui.control.Label
        statusLamp                  matlab.ui.control.Lamp
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        mainUIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        q1 = [-25 25];
        q2 = [-45 45];
        l1 = 10;
        l2 = 10;
        l3 = 10;
        R = 8;
        N = 10; % Description
    end
    
    methods (Access = private)
        
        function drawWSpoint(app)       
            
            %%constants
            RCM = [0 0 app.l1+app.l3];
            yaw_l = sum(app.R*cosd(app.q2));
            pitch_l = sum(app.R*cosd(app.q1));
            
            endX = [];
            endY = [];
            endZ = [];
            
            for Ri = linspace(0,8,app.N)
                for q1i = linspace(app.q1(1),app.q1(2),app.N)
                    for q2i = linspace(app.q2(1),app.q2(2),app.N)
                        endX = [endX Ri.*cosd(q1i).*cosd(q2i)];
                        endY = [endY Ri.*cosd(q1i).*sind(q2i)];
                        endZ = [endZ Ri.*sind(q1i) + RCM(3)];
                        
                    end
                end
            end
            plot3(app.mainUIAxes,endX,endY,endZ,'o','color','red');
            drawnow();
            % axis([0 R -6 6 0 (RCM(3)+R*10)]);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             grid(app.mainUIAxes, 'on');
            zlabel(app.mainUIAxes,"Z");
        end

        % Button pushed function: showButton
        function showButtonPushed(app, event)
                        
            app.statusLamp.Color="red";
            drawnow();
            
            drawWSpoint(app);
            
            app.statusLamp.Color="green";
            drawnow();           
            
        end

        % Value changing function: q1rangeEditField
        function q1rangeEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.q1 = str2double(strsplit(changingValue));
        end

        % Value changing function: q2rangeEditField
        function q2rangeEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.q2 = str2double(strsplit(changingValue));
        end

        % Value changing function: l1EditField
        function l1EditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.l1 = str2double(changingValue);
        end

        % Value changing function: l2EditField
        function l2EditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.l2 = str2double(changingValue);
        end

        % Value changing function: l3EditField
        function l3EditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.l3 = str2double(changingValue);
        end

        % Value changing function: REditField
        function REditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.R = str2double(changingValue);
        end

        % Value changing function: pointsamountEditField
        function pointsamountEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.N = uint8(str2double(changingValue)/3);
            if app.N > 85
                uialert(app.UIFigure,'Point amount should be <= 250','Warning !!!');
                app.N=0;
                app.pointsamountEditField.Value = '';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {455, 455};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {258, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.showButton = uibutton(app.GridLayout3, 'push');
            app.showButton.ButtonPushedFcn = createCallbackFcn(app, @showButtonPushed, true);
            app.showButton.Text = {'show'; ''};
            app.q1rangeEditFieldLabel = uilabel(app.GridLayout3);
            app.q1rangeEditFieldLabel.Text = 'q1 range';
            app.q1rangeEditField = uieditfield(app.GridLayout3, 'text');
            app.q1rangeEditField.ValueChangingFcn = createCallbackFcn(app, @q1rangeEditFieldValueChanging, true);
            app.q1rangeEditField.Value = '-25 25';
            app.q2rangeEditFieldLabel = uilabel(app.GridLayout3);
            app.q2rangeEditFieldLabel.Text = 'q2 range';
            app.q2rangeEditField = uieditfield(app.GridLayout3, 'text');
            app.q2rangeEditField.ValueChangingFcn = createCallbackFcn(app, @q2rangeEditFieldValueChanging, true);
            app.q2rangeEditField.Value = '-45 45';
            app.l1EditFieldLabel = uilabel(app.GridLayout3);
            app.l1EditFieldLabel.Text = 'l1';
            app.l1EditField = uieditfield(app.GridLayout3, 'text');
            app.l1EditField.ValueChangingFcn = createCallbackFcn(app, @l1EditFieldValueChanging, true);
            app.l1EditField.Value = '10';
            app.l2EditFieldLabel = uilabel(app.GridLayout3);
            app.l2EditFieldLabel.Text = 'l2';
            app.l2EditField = uieditfield(app.GridLayout3, 'text');
            app.l2EditField.ValueChangingFcn = createCallbackFcn(app, @l2EditFieldValueChanging, true);
            app.l2EditField.Value = '10';
            app.l3EditFieldLabel = uilabel(app.GridLayout3);
            app.l3EditFieldLabel.Text = 'l3';
            app.l3EditField = uieditfield(app.GridLayout3, 'text');
            app.l3EditField.ValueChangingFcn = createCallbackFcn(app, @l3EditFieldValueChanging, true);
            app.l3EditField.Value = '10';
            app.REditFieldLabel = uilabel(app.GridLayout3);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.GridLayout3, 'text');
            app.REditField.ValueChangingFcn = createCallbackFcn(app, @REditFieldValueChanging, true);
            app.REditField.Value = '8';
            app.pointsamountEditFieldLabel = uilabel(app.GridLayout3);
            app.pointsamountEditFieldLabel.Text = 'points amount';
            app.pointsamountEditField = uieditfield(app.GridLayout3, 'text');
            app.pointsamountEditField.ValueChangingFcn = createCallbackFcn(app, @pointsamountEditFieldValueChanging, true);
            app.pointsamountEditField.Value = '30';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.statusLamp = uilamp(app.GridLayout3);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.mainUIAxes = uiaxes(app.GridLayout2);
            title(app.mainUIAxes, 'End Effector reach points')
            xlabel(app.mainUIAxes, 'X')
            ylabel(app.mainUIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end